classdef BatteryTrackerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        SerialStatusLamp   matlab.ui.control.Lamp
        StartSerialButton  matlab.ui.control.Button
        dataAxes2          matlab.ui.control.UIAxes
        FFT2Axes           matlab.ui.control.UIAxes
        FFT1Axes           matlab.ui.control.UIAxes
        dataAxes1          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        isSerialListening = 0; % 串口是否被打开
        isValidSerialManager = 0; %是否已经创建了一个监听管理
        ratedChannelNum = 2;% 监视的通道数
    end
    
    methods (Access = public)
        
        function serialManager(app)
            while(1)
                if app.isSerialListening == 1 % 当前正在监听
                    app.listenSerial();
                else                            %当前并未监听
                    app.isValidSerialManager = 0; %返回之前清零监听管理存在位
                    app.SerialStatusLamp.Color = [0.80,0.80,0.80];
                    return;
                end
            end
        end

        function listenSerial(app)
            dataProcessor = serialDataProcess();
            try
                serialData = serialReader(1000);
            catch timeOutME
                timeOutME.identifier
            end
            serialData = serialData.serialData;
            dataProcessor.startIndex = dataProcessor.findStartIndex(serialData);
            dataProcessor.serialRlDta = dataProcessor.getRlDta(serialData,app.ratedChannelNum);
            xLabArr = 1:length(dataProcessor.serialRlDta{1});
            plot(app.dataAxes1,xLabArr,dataProcessor.serialRlDta{1});
            xLabArr = 1:length(dataProcessor.serialRlDta{2});
            plot(app.dataAxes2,xLabArr,dataProcessor.serialRlDta{2});
           
            FFTAna = FFTAnalyzer(dataProcessor.serialRlDta{1},dataProcessor.serialRlDta{2},1940);
            [P1V,fv,P1I,fi] = FFTAna.fftAnalis();
            stem(app.FFT1Axes,fv,P1V);
            stem(app.FFT2Axes,fi,P1I);
                
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function AmplitudeSliderValueChanged(app, event)
         
        end

        % Button pushed function: StartSerialButton
        function StartSerialButtonPushed(app, event)
            if app.isSerialListening == 0  % 当前不在监听
                app.isSerialListening = 1;  % 开始监听
                app.StartSerialButton.Text = "停止监听";
                app.SerialStatusLamp.Color = [0 1 0];
                if app.isValidSerialManager == 0 %如果当前没有监听管理
                    app.serialManager();
                end
            else                            %当前正在监听
                app.isSerialListening = 0;  % 正在监听
                app.StartSerialButton.Text = "开始监听";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.dataAxes1 = uiaxes(app.UIFigure);
            title(app.dataAxes1, 'Title')
            xlabel(app.dataAxes1, 'X')
            ylabel(app.dataAxes1, 'Y')
            zlabel(app.dataAxes1, 'Z')
            app.dataAxes1.YLim = [0 3];
            app.FFT1Axes = uiaxes(app.UIFigure);
            title(app.FFT1Axes, 'Title')
            xlabel(app.FFT1Axes, 'X')
            ylabel(app.FFT1Axes, 'Y')
            zlabel(app.FFT1Axes, 'Z')
            app.FFT1Axes.YLim = [0 1];
            app.FFT2Axes = uiaxes(app.UIFigure);
            title(app.FFT2Axes, 'Title')
            xlabel(app.FFT2Axes, 'X')
            ylabel(app.FFT2Axes, 'Y')
            zlabel(app.FFT2Axes, 'Z')
            app.FFT2Axes.YLim = [0 1];
            app.dataAxes2 = uiaxes(app.UIFigure);
            title(app.dataAxes2, 'Title')
            xlabel(app.dataAxes2, 'X')
            ylabel(app.dataAxes2, 'Y')
            zlabel(app.dataAxes2, 'Z')
            app.dataAxes2.YLim = [0 3];
            app.StartSerialButton = uibutton(app.UIFigure, 'push');
            app.StartSerialButton.ButtonPushedFcn = createCallbackFcn(app, @StartSerialButtonPushed, true);
            app.StartSerialButton.Text = '开始监听';
            app.SerialStatusLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BatteryTrackerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end