classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        NetStatusLamp        matlab.ui.control.Lamp
        serverPortEditField  matlab.ui.control.EditField
        Label                matlab.ui.control.Label
        StartServerButton    matlab.ui.control.Button
        WLANEditField        matlab.ui.control.EditField
        WLANEditFieldLabel   matlab.ui.control.Label
        WLANButton           matlab.ui.control.Button
        FFT2Axes             matlab.ui.control.UIAxes
        FFT1Axes             matlab.ui.control.UIAxes
        dataAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        deviceConnector % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function AmplitudeSliderValueChanged(app, event)
            
        end

        % Button pushed function: WLANButton
        function WLANButtonPushed(app, event)
            WifiInf = wlanIPV4Address();
            if contains(WifiInf.wlanStatus, "200")
                app.WLANEditField.Value = WifiInf.ipv4Address;
            elseif contains(WifiInf.wlanStatus, "404")
                app.WLANEditField.Value = "当前设备不支持Wifi";
            elseif contains(WifiInf.wlanStatus, "502")
                app.WLANEditField.Value = "当前设备Wifi已断开";
            end
        end

        % Button pushed function: StartServerButton
        function StartServerButtonPushed(app, event)
            app.NetStatusLamp.Color = [0.07,0.62,1.00];
            if ~isempty(gcp('nocreate'))  % 如果并行工作池存在
                delete(gcp);  % 关闭并行工作池会话
            end
            parpool('local', 1);
            dataMutex = 0;
            dataQueue = cell(1000,2);
            app.deviceConnector = DSP2PCConnector(app.WLANEditField.Value,app.serverPortEditField.Value,dataQueue,dataMutex);
            app.deviceConnector.startServer();
            % serverFuture = parfeval(@app.deviceConnector.startServer,1);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.dataAxes = uiaxes(app.UIFigure);
            title(app.dataAxes, 'Title')
            xlabel(app.dataAxes, 'X')
            ylabel(app.dataAxes, 'Y')
            zlabel(app.dataAxes, 'Z')
            app.FFT1Axes = uiaxes(app.UIFigure);
            title(app.FFT1Axes, 'Title')
            xlabel(app.FFT1Axes, 'X')
            ylabel(app.FFT1Axes, 'Y')
            zlabel(app.FFT1Axes, 'Z')
            app.FFT2Axes = uiaxes(app.UIFigure);
            title(app.FFT2Axes, 'Title')
            xlabel(app.FFT2Axes, 'X')
            ylabel(app.FFT2Axes, 'Y')
            zlabel(app.FFT2Axes, 'Z')
            app.WLANButton = uibutton(app.UIFigure, 'push');
            app.WLANButton.ButtonPushedFcn = createCallbackFcn(app, @WLANButtonPushed, true);
            app.WLANButton.Tag = 'CheckWifiAddressBut';
            app.WLANButton.Text = '检测WLAN地址';
            app.WLANEditFieldLabel = uilabel(app.UIFigure);
            app.WLANEditFieldLabel.Text = 'WLAN地址';
            app.WLANEditField = uieditfield(app.UIFigure, 'text');
            app.StartServerButton = uibutton(app.UIFigure, 'push');
            app.StartServerButton.ButtonPushedFcn = createCallbackFcn(app, @StartServerButtonPushed, true);
            app.StartServerButton.Text = '开始监听';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '当前端口';
            app.serverPortEditField = uieditfield(app.UIFigure, 'text');
            app.serverPortEditField.Value = '28397';
            app.NetStatusLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end